classdef Cobblefinder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        CobbleLoggedLabel          matlab.ui.control.Label
        LogCobbleButton            matlab.ui.control.Button
        TabGroup                   matlab.ui.container.TabGroup
        ControlsTab                matlab.ui.container.Tab
        GridLayout2                matlab.ui.container.GridLayout
        ContinuoutModeSwitch       matlab.ui.control.Switch
        ContinuoutModeSwitchLabel  matlab.ui.control.Label
        RSSIGauge                  matlab.ui.control.LinearGauge
        RSSIGaugeLabel             matlab.ui.control.Label
        TagIDLabel                 matlab.ui.control.Label
        PrevioustagButton          matlab.ui.control.Button
        NexttagButton              matlab.ui.control.Button
        UIAxes4                    matlab.ui.control.UIAxes
        DatalogTab                 matlab.ui.container.Tab
        GridLayout3                matlab.ui.container.GridLayout
        UITable                    matlab.ui.control.Table
        TESTINGTab                 matlab.ui.container.Tab
        ConfigureTab               matlab.ui.container.Tab
        GridLayout4                matlab.ui.container.GridLayout
        GridLayout5                matlab.ui.container.GridLayout
        SnailtrailsCheckBox        matlab.ui.control.CheckBox
        MoplinesCheckBox           matlab.ui.control.CheckBox
        GPSListBox                 matlab.ui.control.ListBox
        GPSSwitch                  matlab.ui.control.Switch
        GPSSwitchLabel             matlab.ui.control.Label
        GPSLamp                    matlab.ui.control.Lamp
        GPSCOMListBox              matlab.ui.control.ListBox
        GPSCOMSwitch               matlab.ui.control.Switch
        GPSCOMSwitchLabel          matlab.ui.control.Label
        GPSCOMLamp                 matlab.ui.control.Lamp
        AntennaCOMListBox          matlab.ui.control.ListBox
        AntennaCOMSwitch           matlab.ui.control.Switch
        AntennaCOMSwitchLabel      matlab.ui.control.Label
        AntennaCOMLamp             matlab.ui.control.Lamp
        UIAxes3                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % App Variables
        GPScord
        MOPlat
        MOPlon
        snail

        %flag
        DSPsnail %make a true defaulted value associated with snail checkbox
        DSPmop %update GPSplot to read these values instead of function inputs
        mappoffset %There must be a more elegant solution to map zoom level
        % maybe do some math to figure out a scale bar
        plotmode % may become unecessary because geoplot mode not working with Uiaxes
        
        % timers
        gpstmr=timer() % initialize variables
    end
    
    methods (Access = private)
        function GPSplot(app, obj, event, string_arg,mapoffset,plotmode,varargin)
            cla(app.UIAxes3)
        
            % 'snail' - display snail trails (previous GPS line)
            if any(strcmp(varargin, 'snail')==1)
                app.snail(end+1,:)=app.GPScord;
                 switch plotmode
                    case 'g'
                        geoplot(app.UIAxes3,app.snail(:,1),app.snail(:,2),'green')
                    case 'p'
                        plot(app.UIAxes3,app.snail(:,2),app.snail(:,1),'green')
                 end
                 hold on
            else
                hold off
            end
        
            % calc map limits
            lat=[app.GPScord(1)-mapoffset,app.GPScord(1)+mapoffset];
            lon=[app.GPScord(2)-mapoffset,app.GPScord(2)+mapoffset];
        
            % Plot map
            switch plotmode
                case 'g' % Geomap
                    geoscatter(app.UIAxes3,app.GPScord(1),app.GPScord(2),150,'blue','x','LineWidth',1);
                    hold on
                    geoscatter(app.UIAxes3,app.GPScord(1),app.GPScord(2),250,'blue','o','LineWidth',1);
            
                    geolimits(lat,lon)
                    geobasemap topographic
                case 'p' % fast plot
                    hold on
                    scatter(app.UIAxes3,app.GPScord(2),app.GPScord(1),150,'blue','x','LineWidth',1);
                    scatter(app.UIAxes3,app.GPScord(2),app.GPScord(1),250,'blue','o','LineWidth',1);
            
                    ylim(app.UIAxes3,lat)
                    xlim(app.UIAxes3,lon)
            end
        
            % Optional arguments (varargin)
            % 'mop' - display mop lines
            if any(strcmp(varargin, 'mop')==1)
                switch plotmode
                    case 'g'
                        hold on
                        geoplot(app.UIAxes3,app.MOPlat,app.MOPlon,'red')
                    case 'p'
                        hold on
                        plot(app.UIAxes3,app.MOPlon,app.MOPlat,'red')
                end
            end
        
%             drawnow()
        end
        %%

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Auto adjust GUI size to fit screen resolution 
            % https://github.com/dseyit/Matlab-App-Designer---Screen-UI-auto-resize.git

            %% Layout parameters
            app.UIFigure.WindowState='maximized'
            app.UIFigure.Units="normalized"

            %% Startup Code
            
            % _____ RSSI _____

            COM="COM4";
            addpath(genpath('C:\Users\ccblack\Documents\Carson\IOD_activeRFID'))
            pause(0.05)

            % Import tag list
            [fid, msg]=fopen('taglist.txt','r');
            if fid < 0
                error('Failed to open file "%s" because: "%s"', filename, msg);
            end
            C = textscan(fid,'%s');
            tagIDlist = hex2dec(C{1});
            fclose(fid);
            num_tags=length(tagIDlist);
            clear C fid ans
            
            % Configure Serial
            Antenna=serialport(COM,9600);
            configureTerminator(Antenna,93); % 93 ascii = ']'
            % Antenna.BytesAvailableFcn
            % serialportlist('all')


            % _____ KMl _____
            [app.MOPlon,app.MOPlat]=MOPelementreducer('MOPs_SD_County.kml');
            
            app.GPScord=[32.927981236100734, -117.25985543852305];
            
            app.snail=app.GPScord;

            %% Startup timers
%             GPSplot(app,'','','',0.001,'p','mop','snail');
            n='p'
            switch n
                case 'g'
                    app.gpstmr=timer('ExecutionMode', 'FixedRate', 'Period', 0.5, ...
                        'TimerFcn', "GPSplot('','',0.001,'g','mop','snail');", ...
                        'StopFcn',"disp('GPS timer stopped')",'ErrorFcn',"disp('GPS erorr!')")
                case 'p'
                    app.gpstmr=timer('ExecutionMode', 'FixedRate', 'Period', 0.12, ...
                        'TimerFcn', {@app.GPSplot,app,'','','',0.001,'p','mop','snail'}, ...
                        'StopFcn',"disp('GPS timer stopped')",'ErrorFcn',"disp('GPS erorr!')") %flag NOT WORKING
            end
            start(app.gpstmr)

        end

        % Value changed function: ContinuoutModeSwitch
        function ContinuoutModeSwitchValueChanged(app, event)
%             compare='On';
%             TF = strcmp(value,compare) %convert value to logical
%             while TF==true
%                 if value==false
%                     break
%                 end
%                 disp('working')
%                 pause(1)
%             end

%% Better method: use a switch and cases to createa a timer function
            switch app.ContinuoutModeSwitch.Value
                case 'On'
%                     t=timer('ExecutionMode', 'FixedRate', ...
%     'Period', 1, ...
%     'TimerFcn', {@CallbackTimer});
                case 'off'
%                     delete(t)
            end
        end

        % Callback function
        function COMPortMenuSelected(app, event)
%             uimenu(app.ContextMenu,'Text','&Open Selection')
            open(app.ContextMenu,100,650)
        end

        % Value changed function: GPSSwitch
        function GPSSwitchValueChanged(app, event)
            value = app.GPSSwitch.Value
            whos value
            switch value
                case 'On'
                    start(app.gpstmr)
                case 'Off'
                    stop(app.gpstmr)
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            stop(timerfind) % stop all timers
            delete(timerfind) % delete all timers
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, 'GPS')
            xlabel(app.UIAxes3, 'Longitude')
            ylabel(app.UIAxes3, 'Latitude')
            zlabel(app.UIAxes3, 'Z')
            app.TabGroup = uitabgroup(app.GridLayout);
            app.ControlsTab = uitab(app.TabGroup);
            app.ControlsTab.Title = 'Controls';
            app.GridLayout2 = uigridlayout(app.ControlsTab);
            app.UIAxes4 = uiaxes(app.GridLayout2);
            title(app.UIAxes4, 'RSSI')
            xlabel(app.UIAxes4, 'Tag ID')
            ylabel(app.UIAxes4, 'Signal Strength')
            zlabel(app.UIAxes4, 'Z')
            app.NexttagButton = uibutton(app.GridLayout2, 'push');
            app.NexttagButton.Text = 'Next tag';
            app.PrevioustagButton = uibutton(app.GridLayout2, 'push');
            app.PrevioustagButton.Text = 'Previous tag';
            app.TagIDLabel = uilabel(app.GridLayout2);
            app.TagIDLabel.Text = 'Tag ID: ';
            app.RSSIGaugeLabel = uilabel(app.GridLayout2);
            app.RSSIGaugeLabel.Text = 'RSSI';
            app.RSSIGauge = uigauge(app.GridLayout2, 'linear');
            app.ContinuoutModeSwitchLabel = uilabel(app.GridLayout2);
            app.ContinuoutModeSwitchLabel.Text = 'Continuout Mode';
            app.ContinuoutModeSwitch = uiswitch(app.GridLayout2, 'slider');
            app.ContinuoutModeSwitch.ValueChangedFcn = createCallbackFcn(app, @ContinuoutModeSwitchValueChanged, true);
            app.DatalogTab = uitab(app.TabGroup);
            app.DatalogTab.Title = 'Datalog';
            app.GridLayout3 = uigridlayout(app.DatalogTab);
            app.UITable = uitable(app.GridLayout3);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.TESTINGTab = uitab(app.TabGroup);
            app.TESTINGTab.Title = 'TESTING';
            app.ConfigureTab = uitab(app.TabGroup);
            app.ConfigureTab.Title = 'Configure';
            app.GridLayout4 = uigridlayout(app.ConfigureTab);
            app.AntennaCOMLamp = uilamp(app.GridLayout4);
            app.AntennaCOMSwitchLabel = uilabel(app.GridLayout4);
            app.AntennaCOMSwitchLabel.Text = 'Antenna COM';
            app.AntennaCOMSwitch = uiswitch(app.GridLayout4, 'slider');
            app.AntennaCOMSwitch.Value = 'On';
            app.AntennaCOMListBox = uilistbox(app.GridLayout4);
            app.AntennaCOMListBox.Enable = 'off';
            app.GPSCOMLamp = uilamp(app.GridLayout4);
            app.GPSCOMSwitchLabel = uilabel(app.GridLayout4);
            app.GPSCOMSwitchLabel.Text = 'GPS COM';
            app.GPSCOMSwitch = uiswitch(app.GridLayout4, 'slider');
            app.GPSCOMSwitch.Value = 'On';
            app.GPSCOMListBox = uilistbox(app.GridLayout4);
            app.GPSCOMListBox.Enable = 'off';
            app.GPSLamp = uilamp(app.GridLayout4);
            app.GPSSwitchLabel = uilabel(app.GridLayout4);
            app.GPSSwitchLabel.Text = 'GPS Map';
            app.GPSSwitch = uiswitch(app.GridLayout4, 'slider');
            app.GPSSwitch.ValueChangedFcn = createCallbackFcn(app, @GPSSwitchValueChanged, true);
            app.GPSSwitch.Value = 'On';
            app.GPSListBox = uilistbox(app.GridLayout4);
            app.GPSListBox.Items = {'G mode', 'P mode', ''};
            app.GPSListBox.Enable = 'off';
            app.GPSListBox.Value = 'G mode';
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.MoplinesCheckBox = uicheckbox(app.GridLayout5);
            app.MoplinesCheckBox.Text = 'Mop lines';
            app.SnailtrailsCheckBox = uicheckbox(app.GridLayout5);
            app.SnailtrailsCheckBox.Text = 'Snail trails';
            app.LogCobbleButton = uibutton(app.GridLayout, 'push');
            app.LogCobbleButton.Text = 'Log Cobble';
            app.CobbleLoggedLabel = uilabel(app.GridLayout);
            app.CobbleLoggedLabel.Text = 'Cobble Logged!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Cobblefinder

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end